classdef SegTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadButton                      matlab.ui.control.Button
        SuperpixelSegmentationLabel     matlab.ui.control.Label
        InFormSegmentationLabel         matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GobackonecellButton             matlab.ui.control.Button
        GotonextcellButton              matlab.ui.control.Button
        SegOptions                      matlab.ui.container.ButtonGroup
        SelectSuperpixelSegmentationButton  matlab.ui.control.RadioButton
        SelectInFormSegmentationButton  matlab.ui.control.RadioButton
        DrawSegmentationonDAPIButton    matlab.ui.control.RadioButton
        RejectCellButton                matlab.ui.control.RadioButton
        DrawSegmentationonMembraneButton  matlab.ui.control.RadioButton
        JumptocellButton                matlab.ui.control.Button
        JumpCell                        matlab.ui.control.NumericEditField
        GoforwardonecellButton          matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        BrightnessSliderLabel           matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        ContrastSliderLabel             matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        ButtonGroup                     matlab.ui.container.ButtonGroup
        DAPIButton                      matlab.ui.control.RadioButton
        MembraneButton                  matlab.ui.control.RadioButton
        ToggleSegmentationButton        matlab.ui.control.StateButton
        Panel_3                         matlab.ui.container.Panel
        SaveTableButton                 matlab.ui.control.Button
    end

    %
    properties (Access = public)
        im_ML % Machine learning label matrix
        im_IF % inForm label matrix
        nRows % rows
        nCols % columns
        mem_dapi_im % dapi and membrane component layers in column vector format
        mycols = [0 0 1; % DAPI
                 1 1 1];   % Membrane
       wd % directory with the inform and ML_data subfolders in it
       fname % current image name
       a_m = 2.5; % 
       a_d = 2.5;
       b_m = 200;
       b_d = 200;
       IF_cells % cell array of cells in inForm label matrix
       ML_cells % cell array of cells in ML label matrix
       m % temporary centroid or midpoint variable passed between functions, created from label matrix 
       IF_table % intial table with all inForm cells in it
       ML_table %intial table with all machine learning cells in it
       cells_n % cells variable passed between functions
       q % temporary table
       overlap_table % final\ resulting table with overlaps and other relevant info
       o_tbl_IF % inform overlap table 
       o_tbl_ML % machine learning overlap table
       n % current cell row in the overlap table for analysis
       ML_Image % the dapi machine learning image
       ML_Image_2 % the membrane and dapi machine learning image
       IF_Image % the dapi inform image
       IF_Image_2 % the membrane and dapi inform image
       cell_obj % the cell object created when drawing a given cell
       rr % the right side index of the image for a given cell cut out
       ll % the left side index of the image for a given cell cut out
       bb % the bottom side index of the image for a given cell cut out
       tt % the top side index of the image for a given cell cut out
       seg_IF % segmentation overlay image for IF
       seg_ML % segmentation overlay image for ML
       IF_c % current cell coordinates for IF
       ML_c % current cell coordinates for ML
       im_d % dapie image 
       im_m % membreane image
       cc % value that tells the next cell button whether or to set ML default in table
       %
    end
    %
    properties (Access = private)
        ClosingDialogApp % closing app dialog
        closing_opts % 1 to close the UI, 0 to leave is open
    end
    %
    methods (Access = private)
        %
        function updateimage(app,imagefile)
            %
            ML_fd = 'superpixel';
            app.fname = extractBetween(imagefile,'Component_Tiffs\','_component');
            app.fname = app.fname{1};
            app.wd = extractBefore(imagefile, ['\',ML_fd,'\Component_Tiffs']);
            % Get the ML image
            try
                for i1 = 1:2
                    im_ML_tmp(:,:,i1) = imread(imagefile, i1);
                end
                app.im_ML = im_ML_tmp;
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Finding ML Image Error');
                return;
            end            
            %
            % get the inform image name and number of layers
            %
            imagefile2 = replace(imagefile, ML_fd, 'inform_data');
            imagefile2 = replace(imagefile2,'_seg','_w_seg');
            %
            try 
                im_info = imfinfo(imagefile2);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Finding inForm Image Error');
                return;
            end
            %
            layers = length(im_info);
            app.nRows = im_info(1).Height;
            app.nCols = im_info(1).Width;
            %
            if layers ~= 15
               uialert(app.UIFigure, 'inForm Image Error: MISSING IMAGE LAYERS');
               return;
            end
            %
            % read INFORM IMAGE
            %
            ic = 0;
            for i1 = 11:15
                ic = ic + 1;
                im_IF_tmp1(:,:,ic) = imread(imagefile2,i1);
            end
            app.im_IF = im_IF_tmp1;
            %
            for i1 = [1, 9]
                im = imread(imagefile2, i1);
                im = im ./ max(im,[],'all');
                im_IF_tmp(:,i1) = reshape(im, [], 1);
            end
            % 
            % read in or create the table
            %
            app.mem_dapi_im = im_IF_tmp(:,[1 9]);
            %
            comp_p_file_IF = [app.wd,'\inform_data\Component_Tiffs\',app.fname,'_comparison_seg_data.csv'];
            %
            if exist(comp_p_file_IF,'file')
               reload_overlap_table(app)
            else
                compute_pairs(app)
            end
            %
            % set up images
            %
            app.a_d = .05 * app.ContrastSlider.Value;
            app.b_d = 4 * app.BrightnessSlider.Value;
            app.im_d =  app.b_d .* asinh(app.a_d .* app.mem_dapi_im(:,1)) * app.mycols(1,:);
            %
            app.a_m = .05 * app.ContrastSlider.Value;
            app.b_m = 4 * app.BrightnessSlider.Value;
            app.im_m =  app.b_m .* asinh(app.a_m .* app.mem_dapi_im(:,2)) * app.mycols(2,:);
            app.im_m = app.im_m + app.im_d;
            %
            % find correct cell
            %
            app.n = find(app.overlap_table.include_cell == 0, 1);
            %
            % generate segmentation mask for current cell
            %
            getSegmap(app)
            %
            disp_images(app,1,app.n,height(app.overlap_table))
            %
            % sets the default values for the cell
            %
            app.SelectSuperpixelSegmentationButton.Value = true;
            inp = app.n;
            app.overlap_table.include_cell(inp) = 2;
            app.overlap_table.final_cells(inp) = app.overlap_table.ML_cells(inp);
        end
        %
        function compute_pairs(app)
            %%
            %
            % this is where the tables are computed for cells and cell overlap pct is
            % assigned
            %
            %%
            %
            % ML cell table
            %
            app.cells_n = label2idx(app.im_ML)';
            im = app.im_ML(:,:,1);
            m1 = regionprops(im,'centroid');
            app.m = struct2cell(m1);
            crt_int_tbl(app, 'ML');
            %
            % get distinct objects in inform
            %
            getIFdistinct(app);
            %
            % create the IF table
            %
            crt_int_tbl(app, 'IF');
            %
            % get the cells which pixels overlap with ML
            %
            comp_overlap(app);
            %
            % add non overlapping cells
            %
            add_no(app);
            %
            % remove edge cells, get linear indicies of all boarder pixels
            %
            rm_edge_cells(app);
            %
            % intialize the final results columns and for 80% or above overlap save
            % ML algorithm segmentation
            %
            int_results_cols(app);
            %
            % save results
            %
            x = app.q;
            app.overlap_table = x;
            saveLabel(app)
            saveTable(app)
            %
        end
        %
        function crt_int_tbl(app, tt)
            %%
            % intialize the table for the overlap computation
            %
            %%
            cells_n1 = app.cells_n;
            m1 = app.m;
            m1 = cell2mat(m1);
            m_o = m1(1:2:end);
            m_e = m1(2:2:end);
            %
            tbl = table();
            tbl.([tt,'_cellid']) = (1:length(cells_n1))';
            tbl.([tt,'_cells']) = cells_n1;
            tbl.([tt,'_X_centroid']) = m_o';
            tbl.([tt,'_Y_centroid']) = m_e';
            tbl.([tt,'_n_pixels']) = cellfun('length',cells_n1);
            %
            if strcmp(tt, 'ML')
                app.ML_table = tbl;
            elseif strcmp(tt,'IF')
                app.IF_table = tbl;
            end
            %
        end
        %
        function getIFdistinct(app)
            %%
            % get the distinct cell objects from both inform cell segmentation layers
            %
            %%
            %
            % get distinct objects in inform
            %
            im = app.im_IF(:,:,2);
            IF_cells_1 = label2idx(im);
            m1 = regionprops(im,'centroid');
            m1 = struct2cell(m1);
            %
            im = app.im_IF(:,:,3);
            IF_cells_2 = label2idx(im);
            m2 = regionprops(im,'centroid');
            m2 = struct2cell(m2);
            %
            if length(IF_cells_1) > length(IF_cells_2)
                nn = length(IF_cells_1);
            else
                nn = length(IF_cells_2);
            end
            %
            IF_cellso = cell(nn, 1);
            mo = cell(1,nn);
            %
            ii = ~cellfun(@isempty,IF_cells_1);
            IF_cellso(ii) = IF_cells_1(ii);
            mo(ii) = m1(ii);
            %
            ii = ~cellfun(@isempty,IF_cells_2);
            IF_cellso(ii) = IF_cells_2(ii);
            mo(ii) = m2(ii);
            %
            app.cells_n = IF_cellso;
            app.m = mo;
            %
        end
        %
        function comp_overlap(app)
            %%
            % This function computes the overlaps and adds them to the appropriate rows
            % in the table
            %
            %%
            o = [];
            for level = 1:2
                im1 = app.im_IF(:,:,level+1);
                overlap = im1 > 0 & app.im_ML > 0;
                pairs = [im1(overlap), app.im_ML(overlap)];
                [ii, ~, kk] = unique(pairs, 'rows');
                pairs_cnt = [ii,accumarray(kk,1)];
                %
                idx1 = pairs_cnt(:,1);
                idx2 = pairs_cnt(:,2);
                %
                crt_ovr_tbl(app,idx1,...
                    pairs_cnt(:,2) ,pairs_cnt(:,3), 'IF');
                %
                crt_ovr_tbl(app,idx2,...
                    pairs_cnt(:,1) ,pairs_cnt(:,3), 'ML');
                %
                tbl = outerjoin(app.o_tbl_IF,app.o_tbl_ML,'MergeKeys',1);
                %
                tbl.IF_level = repmat(level, height(tbl),1);
                o = [o;tbl];
            end
            %
            o.jp = o.IF_pct .* o.ML_pct;
            %
            % find cells with jp < 10% and remove them from the table
            % these cells do not actually overlap well and are probably
            % just beside one another
            %
            ii = o.jp < .1;
            o(ii,:) = [];
            %
            app.q = o;
            %
        end
        %
        function crt_ovr_tbl(app,idx,pairs,cnt,tt)
            %%
            % define the table with the overlaps in it
            %
            %%
            if strcmp(tt, 'ML')
                tbl_in = app.ML_table;
            elseif strcmp(tt, 'IF')
                tbl_in = app.IF_table;
            end
            %
            tbl = tbl_in(idx,:);
            tbl.([tt,'_overlap']) =  cnt;
            tbl.([tt,'_pct']) = double(tbl.([tt,'_overlap']))...
                ./ tbl.([tt,'_n_pixels']);
            tbl.([tt,'_paired_w']) = pairs;
            tbl.cellid = (1:height(tbl))';
            %
            if strcmp(tt, 'ML')
                app.o_tbl_ML = tbl;
            elseif strcmp(tt, 'IF')
                app.o_tbl_IF = tbl;
            end
            %
        end
        %
        function add_no(app)
            %
            tbl = app.q;
            colnames = tbl.Properties.VariableNames;
            %
            z = height(tbl);
            %
            app.IF_table.IF_level = ones(height(app.IF_table), 1);
            im1 = app.im_IF(:,:,3);
            im1 = unique(im1);
            ii = ismember(app.IF_table.IF_cellid, im1);
            app.IF_table.IF_level(ii) = repmat(2, sum(ii), 1);
            %
            IF_tableo = app.IF_table;
            idx1 = unique(IF_tableo.IF_cellid);
            idx2 = unique(tbl.IF_cellid);
            ii = ismember(idx1, idx2);
            %
            no_o_tbl(app, ii, IF_tableo, colnames, z);
            %
            z = height(app.q);
            app.ML_table.IF_level = ones(height(app.ML_table), 1);
            ML_tableo = app.ML_table;
            idx1 = unique(ML_tableo.ML_cellid);
            idx2 = unique(tbl.ML_cellid);
            ii = ismember(idx1, idx2);
            %
            no_o_tbl(app, ii, ML_tableo, colnames, z);
            %
        end
        %
        function no_o_tbl(app, idx, tbl_in, colnames, z)
            %%
            % create the table of the size of the overlap table for cells without
            % overlaps
            %
            %%
            no_tbl = tbl_in;
            no_tbl(idx,:) = [];
            no_tbl.cellid = (z + 1:z + height(no_tbl))';
            colnames_no = no_tbl.Properties.VariableNames;
            ii = ~ismember(colnames, colnames_no);
            tbl = array2table(zeros(height(no_tbl),sum(ii)));
            tbl = [no_tbl, tbl];
            tbl.Properties.VariableNames = [colnames_no,colnames(ii)];
            %
            titles = {'IF','ML'};
            for i1 = 1:2
                if ~ismember([titles{i1},'_cells'], colnames_no)
                    tbl.([titles{i1},'_cells']) = num2cell(tbl.([titles{i1},'_cells']));
                end
            end
            %
            app.q = [app.q; tbl];
            %
        end
        %
        function disp_images(app, nm, n1, nall)
            if nm
                ff = app.fname;
                if nargin > 2
                    n1 = num2str(n1);
                    nall = num2str(nall);
                    app.UIFigure.Name = ['Segmentation Tool: ',ff, '; Cell: ', n1, 'of', nall];
                else
                    app.UIFigure.Name = ['Segmentation Tool: ',ff];
                end
                xl = [1, app.rr - app.ll];
                yl = [1, app.bb - app.tt];
            else
                xl = xlim(app.IF_Image);
                yl = ylim(app.IF_Image);
            end
            %
            imshow2(app, app.ML_Image, 1, 1,xl,yl)
            imshow2(app, app.ML_Image_2, 2, 1,xl,yl)
            %
            imshow2(app, app.IF_Image, 1, 2,xl,yl)
            imshow2(app, app.IF_Image_2, 2, 2,xl,yl)
            %
            linkaxes([app.ML_Image,app.ML_Image_2,app.IF_Image,app.IF_Image_2], 'xy')

            %
        end
        %
        function imshow2(app, loc, layer, s_layer,xl,yl)
            %
            C = app.nCols;
            R = app.nRows;
            %
            % which image to display
            %
            if layer ~= 1
                im = app.im_m;
            else
                im = app.im_d;
            end
            %
            % if segmentation is turned on apply a segmentation masks
            %
            ii = app.ToggleSegmentationButton.Value;
            if ~ii
                if s_layer == 2
                    ii = app.IF_c;
                    im2 = app.seg_IF;
                else
                    ii = app.ML_c;
                    im2 = app.seg_ML;
                end
                aa = .35;
                if length(ii) > 1
                    im(ii,:) = (1-aa) .* im(ii,:) + aa.* im2(ii, :);
                end
            end
            im = reshape(im, R, C, 3);
            %
            im = im(app.tt(1):app.bb(1),app.ll(1):app.rr(1),:);
            %
            im = uint8(im);
            %
            imshow(im, 'Parent', loc);
            xlim(loc, xl)
            ylim(loc, yl)
            %
            %xlim(loc, [app.ll, app.rr])
            %ylim(loc, [app.tt, app.bb])
            %
        end
        %
        function getSegmap(app)
            %
            inp = app.n;
            x = app.overlap_table;
            if x.IF_n_pixels(inp) > x.ML_n_pixels(inp)
                x_center = x.IF_X_centroid(inp);
                y_center = x.IF_Y_centroid(inp);
            else
                x_center = x.ML_X_centroid(inp);
                y_center = x.ML_Y_centroid(inp);
            end
            %
            app.ll = floor(x_center - 50);
            app.rr = floor(x_center + 50);
            app.tt = floor(y_center - 50);
            app.bb = floor(y_center + 50);
            if app.tt < 1
                app.tt = 1;
            end
            if app.bb > app.nRows
                app.bb = app.nRows;
            end
            if app.ll < 1
                app.ll = 1;
            end
            if app.rr > app.nCols
                app.rr = app.nCols;
            end
            %
            %
            ii = x.IF_cells{inp};
            im2 = zeros(app.nCols * app.nRows, 3);
            if length(ii) > 1
                im2(ii,1) = 255;
            end
            app.seg_IF = im2;
            app.IF_c = ii;
            %
            ii = x.ML_cells{inp};
            im2 = zeros(app.nCols * app.nRows, 3);
            if length(ii) > 1
                im2(ii,1) = 255;
            end
            app.seg_ML = im2;
            app.ML_c = ii;
            %
        end
        %
        function rm_edge_cells(app)
            %%
            %
            % this function removes the cells that touch the edges of the images from
            % the training set
            %
            %%
            x = app.q;
            %
            % build an empty image and get the border pixels
            %
            im_e = zeros(app.nRows, app.nCols);
            im_e(1,1:app.nCols) = ones(1, app.nCols);
            im_e(app.nRows,1:app.nCols) = ones(1, app.nCols);
            im_e(1:app.nRows, 1) = ones(1, app.nRows);
            im_e(1:app.nRows, app.nCols) = ones(1, app.nRows);
            ii = find(im_e);
            %
            % find where either the machine learning or the inform algorithm defines
            % cells with any pixels touching the edge of the image
            %
            ML_edges_p = cellfun(@(y) sum(ismember(y, ii)), x.ML_cells);
            IF_edges_p = cellfun(@(y) sum(ismember(y, ii)), x.IF_cells);
            edges_p = ML_edges_p + IF_edges_p;
            %
            % remove those rows from the table
            %
            idx = find(edges_p);
            x(idx,:) = [];
            app.q = x;
            %
        end
        %
        function int_results_cols(app)
            %%
            % this function intializes the results columns and defines the cells that
            % overlap 80% above as the machine learning algorithm cell but defines the
            % cell include parameter as 4 instead of 2
            %
            %%
            x = app.q;
            %
            % results columns
            %
            x.include_cell = zeros(height(x),1);
            c = zeros(height(x),1);
            c = num2cell(c);
            x.final_cells = c;
            %
            % settings for the 80% overlap cells
            %
            ii = x.jp >= .8;
            x.include_cell(ii) = 4;
            x.final_cells(ii) = x.ML_cells(ii);
            %
            % remove empty cells
            %
            ii = isnan(x.ML_X_centroid);
            x(ii,:) = [];
            ii = isnan(x.IF_X_centroid);
            x(ii,:) = [];
            %
            % redefine the cellid
            %
            x.cellid = (1:height(x))';
            %
            app.q = x;
            %
        end
        %
        function saveLabel(app)
            %%
            % create and save the 6 layer label matrix from the overlap_table
            % Order is as follows
            % ML immune
            % ML tumor
            % IF immune
            % IF tumor
            % final immune
            % final tumor
            %
            %%
            %
            for level = 1:2
                %
                ii = app.overlap_table.IF_level == level;
                cells_n1 = app.overlap_table.ML_cells(ii);
                im(:, :, level) = createLabelmatrix(app, cells_n1);
                %
                cells_n1 = app.overlap_table.IF_cells(ii);
                im(:,:,level + 2) = createLabelmatrix(app, cells_n1);
                %
                ii = app.overlap_table.IF_level == level & app.overlap_table.include_cell == 3;
                cells_n1 = app.overlap_table.final_cells(ii);
                im(:,:,level + 4) = createLabelmatrix(app, cells_n1);
                %
            end
            %
            % set image output properties
            %
            ds.Photometric = Tiff.Photometric.MinIsBlack;
            ds.BitsPerSample   = 16;
            ds.SamplesPerPixel = 1;
            ds.SampleFormat = Tiff.SampleFormat.UInt;
            ds.RowsPerStrip = 41;
            ds.MaxSampleValue = 256;
            ds.MinSampleValue = 0;
            ds.PlanarConfiguration = Tiff.PlanarConfiguration.Chunky;
            ds.Software = 'MATLAB';
            ds.ResolutionUnit = Tiff.ResolutionUnit.Inch;
            ds.XResolution = 300;
            ds.YResolution = 300;
            ds.Compression = Tiff.Compression.LZW;
            ds.ImageLength = app.nRows;
            ds.ImageWidth = app.nCols;
            %
            oiname = [app.wd,'\inform_data\Component_Tiffs\',app.fname,...
                '_comparison_seg_data.tif'];
            %
            % write image
            %
            ii = Tiff(oiname,'w');
            %
            ii.setTag(ds)
            d = uint16(im(:,:,1));
            write(ii,d);
            %
            for i3 = 2:(size(im, 3))
                writeDirectory(ii)
                d = uint16(im(:,:,i3));
                ii.setTag(ds);
                write(ii,d);
            end
            %
            close(ii)
            %
        end
        %
        function L = createLabelmatrix(app, cells_n1)
            %%
            % create a label matrix L from the cells in cells_n
            %
            %%
            %
            L = zeros(app.nRows, app.nCols, 'single');
            %
            for i1 = 1:length(cells_n1)
                idx = cells_n1{i1};
                if length(idx) == 1
                    continue
                end
                L(idx) = i1;
            end
            %
        end
        %
        function saveTable(app)
            %%
            % This function saves the app.overlap_table as a .csv file without the
            % cells in it
            %
            %%
            x = app.overlap_table;
            %
            x.final_cells = [];
            x.IF_cells = [];
            x.ML_cells = [];
            %
            ofname = [app.wd,'\inform_data\Component_Tiffs\',app.fname,...
                '_comparison_seg_data.csv'];
            writetable(x, ofname)
            %
        end
        %
        function reload_overlap_table(app)
            %%
            % this function reads in the comparison seg data and label matrix then
            % rearranges to the overlap_table
            %
            %%
            ofname = [app.wd,'\inform_data\Component_Tiffs\',app.fname,...
                '_comparison_seg_data.csv'];
            x = readtable(ofname);
            %
            oiname = [app.wd,'\inform_data\Component_Tiffs\',app.fname,...
                '_comparison_seg_data.tif'];
            for i1 = 1:6
                im(:,:,i1) = imread(oiname, i1);
            end
            %
            x.ML_cells = repmat({0},height(x),1);
            x.IF_cells = repmat({0},height(x),1);
            x.final_cells = repmat({0},height(x),1);
            %
            for level = 1:2
                %
                ii = x.IF_level == level;
                L = im(:,:,level);
                cells_n1 = x.ML_cellid(ii);
                %
                [~,iY,~] = unique(cells_n1, 'last');
                for i1 = 1:length(iY)
                    c = find(L == iY(i1));
                    ii = x.ML_cellid == cells_n1(iY(i1));
                    x.ML_cells(ii) = {c};
                end
                %
                ii = x.IF_level == level;
                L = im(:,:,level + 2);
                cells_n1 = x.IF_cellid(ii);
                %
                [~,iY,~] = unique(cells_n1, 'last');
                for i1 = 1:length(iY)
                    c = find(L == iY(i1));
                    ii = x.IF_cellid == cells_n1(iY(i1));
                    x.IF_cells(ii) = {c};
                end
                %
                ii = x.IF_level == level & x.include_cell == 3;
                L = im(:,:,level + 4);
                cells_n1 = x.cellid(ii);
                %
                Y = unique(cells_n1);
                for i1 = 1:length(Y)
                    c = find(L == i1);
                    x.final_cells(Y(i1)) = {c};
                end
                %
            end
            %
            ii = x.include_cell == 2 | x.include_cell == 4;
            x.final_cells(ii) = x.ML_cells(ii);
            %
            ii = x.include_cell == 1;
            x.final_cells(ii) = x.IF_cells(ii);
            %
            app.overlap_table = x;
            %
        end
        %
    end
    %
    methods (Access = public)
        %
        function returnDialog(app, closing_opts)
            app.closing_opts = closing_opts;
            if app.closing_opts == 1
                delete(app)
            elseif app.closing_opts == 2
                saveLabel(app)
                saveTable(app)
                delete(app)
            end
        end
        %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %
            % Configure image axes
            %
            app.ML_Image = axes('Parent',app.Panel_3);
            app.ML_Image.Box = 'on';
            app.ML_Image.XTick = [];
            app.ML_Image.XTickLabel = [];
            app.ML_Image.YTick = [];
            app.ML_Image.ZTick = [];
            app.ML_Image.Color = [1 1 1];
            app.ML_Image.Units = 'pixels';
            app.ML_Image.Position = [11 297 281 281];
            app.ML_Image.Visible = 'on';
            app.ML_Image.Colormap = gray(256);
            axis(app.ML_Image, 'image');
            %
            app.ML_Image_2 = axes('Parent',app.Panel_3);
            app.ML_Image_2.Box = 'on';
            app.ML_Image_2.XTick = [];
            app.ML_Image_2.XTickLabel = [];
            app.ML_Image_2.YTick = [];
            app.ML_Image_2.ZTick = [];
            app.ML_Image_2.Color = [1 1 1];
            app.ML_Image_2.Units = 'pixels';
            app.ML_Image_2.Position = [11 12 281 281];
            app.ML_Image_2.Visible = 'on';
            app.ML_Image_2.Colormap = gray(256);
            axis(app.ML_Image_2, 'image');
            %
            app.IF_Image = axes('Parent',app.Panel_3);
            app.IF_Image.Box = 'on';
            app.IF_Image.XTick = [];
            app.IF_Image.XTickLabel = [];
            app.IF_Image.YTick = [];
            app.IF_Image.ZTick = [];
            app.IF_Image.Color = [1 1 1];
            app.IF_Image.Units = 'pixels';
            app.IF_Image.Position = [298 297 281 281];
            app.IF_Image.Visible = 'on';
            app.IF_Image.Colormap = gray(256);
            axis(app.IF_Image, 'image');
            %
            app.IF_Image_2 = axes('Parent',app.Panel_3);
            app.IF_Image_2.Box = 'on'; 
            app.IF_Image_2.XTick = [];
            app.IF_Image_2.XTickLabel = [];
            app.IF_Image_2.YTick = [];
            app.IF_Image_2.ZTick = [];
            app.IF_Image_2.Color = [1 1 1];
            app.IF_Image_2.Units = 'pixels';
            app.IF_Image_2.Position = [298 12 281 281];
            app.IF_Image_2.Visible = 'on';
            app.IF_Image_2.Colormap = gray(256);
            axis(app.IF_Image_2, 'image');
            %
            app.ContrastSlider.Value = 50;
            app.BrightnessSlider.Value = 50;
            %
            app.closing_opts = 1;
            app.cc = 1;
            % Update the image and histograms
            % updateimage(app, 'peppers.png');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            %
            % Update the image 
            %
            updateimage(app, app.DropDown.Value);
            %
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            %    
            % if app.n is not empty then there are results and they should
            % be saved before moving on to next image
            %
            if ~isempty(app.n)
                x = app.overlap_table;
                save([app.wd,'\inform_data\Component_Tiffs\',app.fname, '_overlap_table'],'x')
            end
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            figure(app.UIFigure)
            %
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Opening the image file and computing new or finding overlap table');
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               imagefile = [p f];
               updateimage(app, imagefile);
            end
            %
            d.Value = 1;
            pause(.5)
            close(d);
            %
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            %
            if ~isempty(app.n)
                %
                ii = app.DAPIButton.Value;
                if ii
                    app.b_d = 4 * app.BrightnessSlider.Value;
                    app.im_d =  app.b_d .* asinh(app.a_d .* app.mem_dapi_im(:,1)) * app.mycols(1,:);
                end
                %
                ii = app.MembraneButton.Value;
                if ii
                    app.b_m = 4 * app.BrightnessSlider.Value;
                end
                app.im_m =  app.b_m .* asinh(app.a_m .* app.mem_dapi_im(:,2)) * app.mycols(2,:);
                app.im_m = app.im_m + app.im_d;
                %
                disp_images(app, 0)
                %
            end
            %
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            %
            if ~isempty(app.n)
                %
                ii = app.DAPIButton.Value;
                if ii
                    app.a_d = .05 * app.ContrastSlider.Value;
                    app.im_d =  app.b_d .* asinh(app.a_d .* app.mem_dapi_im(:,1)) * app.mycols(1,:);
                end
                %
                ii = app.MembraneButton.Value;
                if ii
                    app.a_m = .05 * app.ContrastSlider.Value;
                end
                %
                app.im_m =  app.b_m .* asinh(app.a_m .* app.mem_dapi_im(:,2)) * app.mycols(2,:);
                app.im_m = app.im_m + app.im_d;
                %
                disp_images(app, 0)
                %
            end
            %
        end

        % Value changed function: ToggleSegmentationButton
        function ToggleSegmentationButtonValueChanged(app, event)
            %
            if ~isempty(app.n)    
                disp_images(app, 0)
            end
            %
        end

        % Button pushed function: GobackonecellButton
        function GobackonecellButtonPushed(app, event)
            %
            if ~isempty(app.n) && app.n > 1
                ii = 4;
                %
                while ii == 4
                        app.n = app.n - 1;
                     ii = app.overlap_table.include_cell(app.n);
                end
                %
                app.cc = 1;
                %
                % reset segmentation mask
                %
                app.ToggleSegmentationButton.Value = false;
                %
                % set the radio button to appropriate value
                %
                if ii == 1
                    app.SelectInFormSegmentationButton.Value = true;
                elseif ii == 2
                    app.SelectSuperpixelSegmentationButton.Value = true;
                elseif ii == -1
                    app.RejectCellButton.Value = true;
                else
                    app.DrawSegmentationonDAPIButton.Value = true;
                end
                %
                getSegmap(app)
                disp_images(app, 1, app.n, height(app.overlap_table));
            end
            %
        end

        % Button pushed function: GotonextcellButton
        function GotonextcellButtonPushed(app, event)
            %
            if ~isempty(app.n)
                %
                if app.cc == 0
                    %
                    % sets the default value for the cell if no changes
                    % have taken place
                    %
                    inp = app.n;
                    app.overlap_table.include_cell(inp) = 2;
                    app.overlap_table.final_cells(inp) = app.overlap_table.ML_cells(inp);
                end
                app.n = find(app.overlap_table.include_cell == 0, 1);
                app.cc = 0;
                %
                % reset segmentation mask
                %
                app.ToggleSegmentationButton.Value = false;
                % 
                % get the segmentation mask and set it to display the new
                % images
                %
                getSegmap(app)
                disp_images(app, 1, app.n, height(app.overlap_table))
                %
                % resets to ML
                %
                app.SelectSuperpixelSegmentationButton.Value = true;
                %
            end
            %
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            %
            if ~isempty(app.n)
                saveLabel(app)
                saveTable(app)
            end
            %
        end

        % Selection changed function: SegOptions
        function SegOptionsSelectionChanged(app, event)
            %
            % release a draw object if exists
            %
            if ~isempty(app.cell_obj)
                delete(app.cell_obj);
            end
            %
            % tell app there was a change in the selection, so that it does
            % not overwrite with the default
            %
            app.cc = 1;
            %
            % Update the chart according to which segmentation option is
            % selected if the cell counter exists. Default is ML
            % Segmentation
            %
            if ~isempty(app.n)
                %
                 if app.DrawSegmentationonDAPIButton.Value
                     draw_im = app.IF_Image;
                 elseif app.DrawSegmentationonMembraneButton.Value
                     draw_im = app.IF_Image_2;
                 else
                     draw_im = [];
                     %
                     robot = java.awt.Robot;
                     robot.keyPress    (java.awt.event.KeyEvent.VK_ESCAPE);
                     robot.keyRelease  (java.awt.event.KeyEvent.VK_ESCAPE);
                     %
                 end
                 %
                 if ~isempty(draw_im)
                    inp = app.n;
                    disp_images(app, 0);
                    app.overlap_table.include_cell(inp) = 3;
                    pp = 1;
                    while pp == 1
                        app.cell_obj = drawfreehand(draw_im, 'InteractionsAllowed','none',...
                                                        'FaceAlpha', .2, 'Multiclick',true);
                        try
                            mm = createMask(app.cell_obj);
                            pp = 0;
                        catch
                            if ~isempty(app.cell_obj)
                                 delete(app.cell_obj);
                            end
                        end
                    end
                    % map back to the image
                    ima = zeros(app.nRows,app.nCols);
                    ima(app.tt(1):app.bb(1),app.ll(1):app.rr(1)) = mm;
                    %
                    idx = find(ima > 0);
                    app.overlap_table.final_cells(inp) = {idx};
                 end
                 %
                 if app.SelectSuperpixelSegmentationButton.Value
                     inp = app.n;
                     app.overlap_table.include_cell(inp) = 2;
                     app.overlap_table.final_cells(inp) = app.overlap_table.ML_cells(inp);
                end
                %
                if app.SelectInFormSegmentationButton.Value
                    inp = app.n;
                    app.overlap_table.include_cell(inp) = 1;
                    app.overlap_table.final_cells(inp) = app.overlap_table.IF_cells(inp);
                end
                %
                if app.RejectCellButton.Value
                    inp = app.n;
                    app.overlap_table.include_cell(inp) = -1;
                end
                %
            end
            %
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % set the sliders to the appropriate values for the selection
            if app.DAPIButton.Value
                app.ContrastSlider.Value = app.a_d /.05;
                app.BrightnessSlider.Value = app.b_d / 4;
            end
            %
            if app.MembraneButton.Value
                app.ContrastSlider.Value = app.a_m / .05;
                app.BrightnessSlider.Value = app.b_m / 4;
            end
            %
        end

        % Button pushed function: JumptocellButton
        function JumptocellButtonPushed(app, event)
            %
            if ~isempty(app.n)
                %
                inp = app.JumpCell.Value;
                app.cc = 1;
                if inp > 0 && inp < height(app.overlap_table)
                    %
                    app.n = inp;
                    %
                    % reset segmentation mask
                    %
                    app.ToggleSegmentationButton.Value = false;
                    %
                    % set the radio button to appropriate value
                    %
                    ii = app.overlap_table.include_cell(app.n);
                    if ii == 1
                        app.SelectInFormSegmentationButton.Value = true;
                    elseif ii == 2 || ii == 4 || ii == 0
                        app.SelectSuperpixelSegmentationButton.Value = true;
                    elseif ii == -1
                        app.RejectCellButton.Value = true;
                    else
                        app.DrawSegmentationonDAPIButton.Value = true;
                    end
                    %
                    getSegmap(app)
                    disp_images(app, 1, app.n, height(app.overlap_table));
                    %
                end
                %
            end
            %
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %
            if ~isempty(app.n)
                app.ClosingDialogApp = ClosingDialog(app);
            else
                delete(app)
            end
            %
        end

        % Button pushed function: GoforwardonecellButton
        function GoforwardonecellButtonPushed(app, event)
            %
            if ~isempty(app.n) && app.n > 1
                ii = 4;
                %
                while ii == 4
                    app.n = app.n + 1;
                    ii = app.overlap_table.include_cell(app.n);
                end
                %
                app.cc = 1;
                %
                % reset segmentation mask
                %
                app.ToggleSegmentationButton.Value = false;
                %
                % set the radio button to appropriate value
                %
                if ii == 1
                    app.SelectInFormSegmentationButton.Value = true;
                elseif ii == 2
                    app.SelectSuperpixelSegmentationButton.Value = true;
                elseif ii == -1
                    app.RejectCellButton.Value = true;
                else
                    app.DrawSegmentationonDAPIButton.Value = true;
                end
                %
                getSegmap(app)
                disp_images(app, 1, app.n, height(app.overlap_table));
            end
            %
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Segmentation Tool';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load new image ';
            app.SuperpixelSegmentationLabel = uilabel(app.UIFigure);
            app.SuperpixelSegmentationLabel.Text = 'Superpixel Segmentation';
            app.InFormSegmentationLabel = uilabel(app.UIFigure);
            app.InFormSegmentationLabel.Text = 'InForm Segmentation';
            app.Panel = uipanel(app.UIFigure);
            app.GobackonecellButton = uibutton(app.Panel, 'push');
            app.GobackonecellButton.ButtonPushedFcn = createCallbackFcn(app, @GobackonecellButtonPushed, true);
            app.GobackonecellButton.Text = 'Go back one cell';
            app.GotonextcellButton = uibutton(app.Panel, 'push');
            app.GotonextcellButton.ButtonPushedFcn = createCallbackFcn(app, @GotonextcellButtonPushed, true);
            app.GotonextcellButton.Text = 'Go to next cell';
            app.SegOptions = uibuttongroup(app.Panel);
            app.SegOptions.SelectionChangedFcn = createCallbackFcn(app, @SegOptionsSelectionChanged, true);
            app.SelectSuperpixelSegmentationButton = uiradiobutton(app.SegOptions);
            app.SelectSuperpixelSegmentationButton.Text = {'     Select Superpixel       '; '        Segmentation          '};
            app.SelectSuperpixelSegmentationButton.Value = true;
            app.SelectInFormSegmentationButton = uiradiobutton(app.SegOptions);
            app.SelectInFormSegmentationButton.Text = {'             Select                 '; '  InForm Segmentation  '};
            app.DrawSegmentationonDAPIButton = uiradiobutton(app.SegOptions);
            app.DrawSegmentationonDAPIButton.Text = {'   Draw Segmentation    '; '            on DAPI              '};
            app.RejectCellButton = uiradiobutton(app.SegOptions);
            app.RejectCellButton.Text = '         Reject Cell             ';
            app.DrawSegmentationonMembraneButton = uiradiobutton(app.SegOptions);
            app.DrawSegmentationonMembraneButton.Text = {'   Draw Segmentation     '; '        on Membrane         '};
            app.JumptocellButton = uibutton(app.Panel, 'push');
            app.JumptocellButton.ButtonPushedFcn = createCallbackFcn(app, @JumptocellButtonPushed, true);
            app.JumptocellButton.Text = 'Jump to cell:';
            app.JumpCell = uieditfield(app.Panel, 'numeric');
            app.JumpCell.ValueDisplayFormat = '%.0f';
            app.GoforwardonecellButton = uibutton(app.Panel, 'push');
            app.GoforwardonecellButton.ButtonPushedFcn = createCallbackFcn(app, @GoforwardonecellButtonPushed, true);
            app.GoforwardonecellButton.Text = 'Go forward one cell';
            app.Panel_2 = uipanel(app.UIFigure);
            app.BrightnessSliderLabel = uilabel(app.Panel_2);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.Panel_2);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.ContrastSliderLabel = uilabel(app.Panel_2);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.Panel_2);
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [];
            app.ButtonGroup = uibuttongroup(app.Panel_2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.DAPIButton = uiradiobutton(app.ButtonGroup);
            app.DAPIButton.Text = 'DAPI';
            app.DAPIButton.Value = true;
            app.MembraneButton = uiradiobutton(app.ButtonGroup);
            app.MembraneButton.Text = 'Membrane';
            app.ToggleSegmentationButton = uibutton(app.Panel_2, 'state');
            app.ToggleSegmentationButton.ValueChangedFcn = createCallbackFcn(app, @ToggleSegmentationButtonValueChanged, true);
            app.ToggleSegmentationButton.Text = 'Toggle Segmentation';
            app.Panel_3 = uipanel(app.UIFigure);
            app.SaveTableButton = uibutton(app.UIFigure, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Text = 'Save Table';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SegTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end